classdef gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        Label_2                         matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        Lamp_2                          matlab.ui.control.Lamp
        LoadMVCButton                   matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        Image_2                         matlab.ui.control.Image
        MethodDropDown                  matlab.ui.control.DropDown
        MethodDropDownLabel             matlab.ui.control.Label
        AutomaticfilterparametersLabel  matlab.ui.control.Label
        Switch_2                        matlab.ui.control.Switch
        FrequencyAnalysisLabel          matlab.ui.control.Label
        Switch                          matlab.ui.control.Switch
        StandarddeviationSlider         matlab.ui.control.Slider
        StandarddeviationSliderLabel    matlab.ui.control.Label
        OutlierremovalLabel             matlab.ui.control.Label
        CleardataButton                 matlab.ui.control.Button
        ExportdataButton                matlab.ui.control.Button
        LoaddataButton                  matlab.ui.control.Button
        DataManagementLabel             matlab.ui.control.Label
        CenterPanel                     matlab.ui.container.Panel
        OpeninnewwindowButton           matlab.ui.control.Button
        ProcessingtracksLabel           matlab.ui.control.Label
        UIAxes_2                        matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
        RightPanel                      matlab.ui.container.Panel
        AddtoexportCheckBox             matlab.ui.control.CheckBox
        ShowongraphCheckBox             matlab.ui.control.CheckBox
        MVCLabel                        matlab.ui.control.Label
        Slider                          matlab.ui.control.Slider
        RMSwindowsizeLabel              matlab.ui.control.Label
        DefaultButton                   matlab.ui.control.Button
        OrderEditField                  matlab.ui.control.NumericEditField
        OrderEditFieldLabel             matlab.ui.control.Label
        CutoffEditField                 matlab.ui.control.NumericEditField
        CutoffEditFieldLabel            matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        FilterDropDown                  matlab.ui.control.DropDown
        FilterDropDownLabel             matlab.ui.control.Label
        FilterparametersLabel           matlab.ui.control.Label
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function
        function FilterDropDownValueChanged(app, event)
            value = app.FilterDropDown.Value;
            
        end

        % Button pushed function: LoaddataButton
        function LoaddataButtonPushed(app, event)
            [file,path] = uigetfile('*.txt');
            if isequal(file,0)
               disp('User selected Cancel');
            else
               disp(['User selected ', fullfile(path,file)]);
               app.Lamp.Color = [0 1 0];
            end
        end

        % Button pushed function: ExportdataButton
        function ExportdataButtonPushed(app, event)
            uigetdir(matlabroot,'Choose folder to save the analysis');
            app.Label_2.Visible = 'on';
        end

        % Button pushed function: LoadMVCButton
        function LoadMVCButtonPushed(app, event)
            [file,path] = uigetfile('*.txt');
            if isequal(file,0)
               disp('User selected Cancel');
            else
               disp(['User selected ', fullfile(path,file)]);
               app.Lamp_2.Color = [0 1 0];
            end   
        end

        % Button pushed function: CleardataButton
        function CleardataButtonPushed(app, event)

            selection = uiconfirm(app.UIFigure,'Clear data?','Confirm Clearing',...
                                    'Icon','warning');
            % Handle response
            switch selection
                case 'OK'
                    disp('Clearing...')
                    app.Lamp_2.Color = [1 0 0];
                    app.Lamp.Color = [1 0 0];
                case 'Cancel'
                    disp('...')
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {480, 480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x', 220};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DataManagementLabel = uilabel(app.LeftPanel);
            app.DataManagementLabel.Text = 'Data Management';
            app.LoaddataButton = uibutton(app.LeftPanel, 'push');
            app.LoaddataButton.ButtonPushedFcn = createCallbackFcn(app, @LoaddataButtonPushed, true);
            app.LoaddataButton.Text = 'Load data';
            app.ExportdataButton = uibutton(app.LeftPanel, 'push');
            app.ExportdataButton.ButtonPushedFcn = createCallbackFcn(app, @ExportdataButtonPushed, true);
            app.ExportdataButton.Text = 'Export data';
            app.CleardataButton = uibutton(app.LeftPanel, 'push');
            app.CleardataButton.ButtonPushedFcn = createCallbackFcn(app, @CleardataButtonPushed, true);
            app.CleardataButton.Text = 'Clear data';
            app.OutlierremovalLabel = uilabel(app.LeftPanel);
            app.OutlierremovalLabel.Text = 'Outlier removal';
            app.StandarddeviationSliderLabel = uilabel(app.LeftPanel);
            app.StandarddeviationSliderLabel.Text = 'Standard deviation';
            app.StandarddeviationSlider = uislider(app.LeftPanel);
            app.StandarddeviationSlider.Limits = [1 3];
            app.StandarddeviationSlider.MajorTicks = [1 2 3];
            app.StandarddeviationSlider.MajorTickLabels = {'1', '2', '3'};
            app.StandarddeviationSlider.MinorTicks = [];
            app.StandarddeviationSlider.Value = 1;
            app.Switch = uiswitch(app.LeftPanel, 'slider');
            app.Switch.Value = 'On';
            app.FrequencyAnalysisLabel = uilabel(app.LeftPanel);
            app.FrequencyAnalysisLabel.Text = 'Frequency Analysis';
            app.Switch_2 = uiswitch(app.LeftPanel, 'slider');
            app.Switch_2.Value = 'On';
            app.AutomaticfilterparametersLabel = uilabel(app.LeftPanel);
            app.AutomaticfilterparametersLabel.Text = {'Automatic '; 'filter parameters'};
            app.MethodDropDownLabel = uilabel(app.LeftPanel);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.LeftPanel);
            app.MethodDropDown.Items = {'STD', 'IQR', 'Z-Score', 'Percentile', 'Winsorization'};
            app.MethodDropDown.Value = 'STD';
            app.Image_2 = uiimage(app.LeftPanel);
            app.Image_2.ImageSource = 'logo.png';
            app.Lamp = uilamp(app.LeftPanel);
            app.LoadMVCButton = uibutton(app.LeftPanel, 'push');
            app.LoadMVCButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMVCButtonPushed, true);
            app.LoadMVCButton.Text = 'Load MVC';
            app.Lamp_2 = uilamp(app.LeftPanel);
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = '⚠️';
            app.Label_2 = uilabel(app.LeftPanel);
            app.Label_2.Visible = 'off';
            app.Label_2.Text = '✓';
            app.CenterPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.CenterPanel);
            title(app.UIAxes, 'Rectification with Band-pass')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.CenterPanel);
            title(app.UIAxes_2, 'Moving RMS window')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.ProcessingtracksLabel = uilabel(app.CenterPanel);
            app.ProcessingtracksLabel.Text = 'Processing tracks';
            app.OpeninnewwindowButton = uibutton(app.CenterPanel, 'push');
            app.OpeninnewwindowButton.Text = 'Open in new window';
            app.RightPanel = uipanel(app.GridLayout);
            app.FilterparametersLabel = uilabel(app.RightPanel);
            app.FilterparametersLabel.Text = 'Filter parameters';
            app.FilterDropDownLabel = uilabel(app.RightPanel);
            app.FilterDropDownLabel.Text = 'Filter';
            app.FilterDropDown = uidropdown(app.RightPanel);
            app.FilterDropDown.Items = {'Band-pass', 'Low-pass', 'High-pass', 'Notch', 'Savitzky-Golay'};
            app.FilterDropDown.Value = 'Band-pass';
            app.Image = uiimage(app.RightPanel);
            app.CutoffEditFieldLabel = uilabel(app.RightPanel);
            app.CutoffEditFieldLabel.Text = 'Cutoff';
            app.CutoffEditField = uieditfield(app.RightPanel, 'numeric');
            app.OrderEditFieldLabel = uilabel(app.RightPanel);
            app.OrderEditFieldLabel.Text = 'Order';
            app.OrderEditField = uieditfield(app.RightPanel, 'numeric');
            app.DefaultButton = uibutton(app.RightPanel, 'push');
            app.DefaultButton.Text = 'Default';
            app.RMSwindowsizeLabel = uilabel(app.RightPanel);
            app.RMSwindowsizeLabel.Text = 'RMS window size';
            app.Slider = uislider(app.RightPanel);
            app.MVCLabel = uilabel(app.RightPanel);
            app.MVCLabel.Text = 'MVC';
            app.ShowongraphCheckBox = uicheckbox(app.RightPanel);
            app.ShowongraphCheckBox.Text = 'Show on graph';
            app.AddtoexportCheckBox = uicheckbox(app.RightPanel);
            app.AddtoexportCheckBox.Text = 'Add to export';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = gui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end